classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Alive                     matlab.ui.control.EditField
        AlivetimerEditFieldLabel  matlab.ui.control.Label
        ConnectionStatus          matlab.ui.control.Lamp
        PortField                 matlab.ui.control.EditField
        PortEditFieldLabel        matlab.ui.control.Label
        PlatformSwitch            matlab.ui.control.Switch
        LoadButton                matlab.ui.control.Button
        xDataEditField            matlab.ui.control.EditField
        xDataEditFieldLabel       matlab.ui.control.Label
        nDataEditField            matlab.ui.control.EditField
        nDataEditFieldLabel       matlab.ui.control.Label
        iDEditField               matlab.ui.control.EditField
        iDEditFieldLabel          matlab.ui.control.Label
        SendButton                matlab.ui.control.Button
        OpenButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        s % Description
        s_port = '/dev/ttyACM1' % Description
        iD string % Description
        nData string % Description
        xData string % Description
    end
    
    methods (Access = private)
        
        function SerialPortCallBack010(app, ~, ~)% only one switch for Id
            try
                [iD, nData, xData] = readDataSTM32(app.s);

                switch iD
                case 1010
                    app.Alive.Value = string(xData);
                    % toc; tic;
                case 11
                    if nData>0
                        % plot_x(xData);
                        %figure();
                        %plot(xData)
                    end
                case 20
                    app.iDEditField.Value = string(iD);
                    app.nDataEditField.Value = string(nData);
                    app.xDataEditField.Value = num2str(xData);
                end
            catch ME
                disp(ME.message) % rethrow(ME)
            end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            try
                   
                %app.s = serialport('/dev/ttyACM1', 2*115200);
                app.s = serialport(app.s_port, 2*115200);
                app.ConnectionStatus.Color = 'green';
                flush(app.s);
                %configureCallback(app.s ,"byte", 4, @app.SerialPortCallBack011);
                configureCallback(app.s ,"byte", 4, @app.SerialPortCallBack010);
                % configureCallback(app.s ,"char", 10, @app.SerialPortCallBack011);
            catch ME
                disp(ME.message)
                app.ConnectionStatus.Color = 'red';
                % rethrow(ME)
            end 
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
                
            %x = 1:10;
            x = randi([0, 64], 1, 10);
            writeDataSTM32(app.s, 20, 10, x);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.iD, app.nData, app.xData] = readDataSTM32(app.s);

            app.iDEditField.Value = app.iD;
            app.nDataEditField.Value = app.nData;
            app.xDataEditField.Value = app.xData;
        end

        % Value changing function: iDEditField
        function iDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: PlatformSwitch
        function PlatformSwitchValueChanged(app, event)
            value = app.PlatformSwitch.Value;
            
            if strcmp(value,'Linux')
                disp('Linux')
                port = '/dev/ttyACM1';
            elseif strcmp(value,'Windows')
                disp('Windows')
                port = 'COM5';
            end
            
            app.s_port = port;
            app.PortField.Value = port;
        end

        % Value changed function: PortField
        function PortFieldValueChanged(app, event)
            value = app.PortField.Value;
            
            app.s_port = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.iDEditFieldLabel = uilabel(app.UIFigure);
            app.iDEditFieldLabel.Text = 'iD';
            app.iDEditField = uieditfield(app.UIFigure, 'text');
            app.iDEditField.ValueChangingFcn = createCallbackFcn(app, @iDEditFieldValueChanging, true);
            app.nDataEditFieldLabel = uilabel(app.UIFigure);
            app.nDataEditFieldLabel.Text = 'nData';
            app.nDataEditField = uieditfield(app.UIFigure, 'text');
            app.xDataEditFieldLabel = uilabel(app.UIFigure);
            app.xDataEditFieldLabel.Text = 'xData';
            app.xDataEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlatformSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlatformSwitch.Items = {'Linux', 'Windows'};
            app.PlatformSwitch.ValueChangedFcn = createCallbackFcn(app, @PlatformSwitchValueChanged, true);
            app.PlatformSwitch.Value = 'Linux';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortField = uieditfield(app.UIFigure, 'text');
            app.PortField.ValueChangedFcn = createCallbackFcn(app, @PortFieldValueChanged, true);
            app.PortField.Value = '/dev/ttyACM1';
            app.ConnectionStatus = uilamp(app.UIFigure);
            app.AlivetimerEditFieldLabel = uilabel(app.UIFigure);
            app.AlivetimerEditFieldLabel.Text = 'Alive timer';
            app.Alive = uieditfield(app.UIFigure, 'text');
            app.Alive.Editable = 'off';
            app.Alive.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end